classdef SaveFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        BrowseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        SpecificSpikesAdj   % Description
        Recordings
        TimeLineSWI
        DetectionParameters % Description
        montageChoice
        Stats
        pathToSave
    end
    
    methods (Access = public)
        
        function app = saveFile(app, SpecificSpikesAdj, Recordings, TimeLineSWI, DetectionParameters, montageChoice, Stats)
            app.SpecificSpikesAdj = SpecificSpikesAdj;
            app.Recordings = Recordings;
            app.TimeLineSWI = TimeLineSWI;
            app.DetectionParameters = DetectionParameters;
            app.montageChoice = montageChoice;
            app.Stats = Stats;
            file = strrep(app.Recordings.name,'.EDF', '');
            app.FileNameEditField.Value = file;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir(pwd, "Select folder");
            app.pathToSave = path;
            app.TextArea.Value = path;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Create structure to reuse analysis
            saveData.SpecificSpikesAdj = app.SpecificSpikesAdj;
            saveData.Recordings = app.Recordings;
            saveData.TimeLineSWI = app.TimeLineSWI;
            saveData.DetectionParameters = app.DetectionParameters;
            saveData.montageChoice = app.montageChoice;
            saveData.Stats = app.Stats;
            file = app.FileNameEditField.Value;
            path = string(app.pathToSave) + "\" + string(file);
            save(path, 'saveData');
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TextArea = uitextarea(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end