classdef StartGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        sLabel                       matlab.ui.control.Label
        TotalfiledurationEditField   matlab.ui.control.NumericEditField
        TotalfiledurationEditFieldLabel  matlab.ui.control.Label
        MontageDropDown              matlab.ui.control.DropDown
        MontagechoiceLabel           matlab.ui.control.Label
        LoadpreviousanalysisButton   matlab.ui.control.Button
        inminutescantgobelow2minutesLabel  matlab.ui.control.Label
        insecondsLabel_2             matlab.ui.control.Label
        insecondsLabel               matlab.ui.control.Label
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        SliceDurationLabel           matlab.ui.control.Label
        SliceRecordingDropDown       matlab.ui.control.DropDown
        SliceRecordingDropDownLabel  matlab.ui.control.Label
        DurationofanalysisLabel      matlab.ui.control.Label
        StartofanalysisLabel         matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        VLabel                       matlab.ui.control.Label
        ChangeAmplitudeofgenerictemplateforSpikedetectionLabel  matlab.ui.control.Label
        StartAnalysisButton          matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        ChoosefiletoanalyzeButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        fileName                % Description
        amplitudeValue = 50     % Description
        startValue = 0
        durationValue = 60
        sliceValue = 'No'
        durationSliceValue = 5;
        montageChoice = 1       % Description
        N                       % Description
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosefiletoanalyzeButton
        function ChoosefiletoanalyzeButtonPushed(app, event)

            filePath = [pwd '\Data\'];
            [app.fileName, ~] = uigetfile(...
                {'*.edf;*.mat','Accepted files (*.edf,*.mat)';...
                '*.edf','EDF-files (*.edf)';...
                '*.mat','MAT-files (*.mat)';...
                '*.*',  'All Files (*.*)'},...
                'Choose the file:',...
                'MultiSelect','off',filePath);
            
            % Load file
%             if ~isequal(app.fileName,0)
%                 fileData = cell(length(app.fileName));
%                 if isa(app.fileName,'cell')
%                     for i=1:length(app.fileName)
%                         file(i).Name = app.fileName{i};
%                         if endsWith(app.fileName{i},'.mat')
%                             fileData{i} = load([path '\' app.fileName{i}]);
%                         end
%                     end
%                else
%                    file.Name = app.fileName;
%                    if endsWith(app.fileName,'.mat')
%                        fileData = load([path '\' app.fileName]);
%                    end
%                end 
%             else
%                disp('No file(s) selected')
%             end
        
            app.TextArea.Value = app.fileName;
            name = [pwd '\Data\' app.fileName];
            path = [pwd '\Code v3\functions'];
            addpath(path); 
            [~,~,~,~,~,~,~,~,~,app.N] = Readedf(name,0,0,0.1);
            if app.N > 0
                app.TotalfiledurationEditField.Visible = 'on';
                app.sLabel.Visible = 'on';
                app.TotalfiledurationEditFieldLabel.Visible = 'on';
                app.TotalfiledurationEditField.Value = app.N;
            end
                
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
    
            setappdata(gcf,'ampValue',app.amplitudeValue);
            setappdata(gcf,'startValue',app.startValue);
            setappdata(gcf,'durationValue',app.durationValue);
            setappdata(gcf,'sliceValue',app.sliceValue);
            setappdata(gcf,'durationSliceValue',app.durationSliceValue);
            setappdata(gcf,'montageChoice',app.montageChoice);
            setappdata(gcf,'fileName',app.fileName);
            path = [pwd '\Code v3\functions'];
            addpath(path);  
            Main
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            % Open 2nd instance of app
            %StartGUI();  
            % Delete old instance
            close(app.UIFigure);
            
            AskNewAnalysis();

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.amplitudeValue = app.EditField.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.startValue = app.EditField_2.Value;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.durationValue = app.EditField_3.Value;
        end

        % Value changed function: SliceRecordingDropDown
        function SliceRecordingDropDownValueChanged(app, event)
            app.sliceValue = app.SliceRecordingDropDown.Value;
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.durationSliceValue = app.EditField_4.Value; 
        end

        % Button pushed function: LoadpreviousanalysisButton
        function LoadpreviousanalysisButtonPushed(app, event)
            LoadFile;
            close(app.UIFigure);
        end

        % Value changed function: MontageDropDown
        function MontageDropDownValueChanged(app, event)
            app.montageChoice = app.MontageDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosefiletoanalyzeButton = uibutton(app.UIFigure, 'push');
            app.ChoosefiletoanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefiletoanalyzeButtonPushed, true);
            app.ChoosefiletoanalyzeButton.Text = 'Choose file to analyze';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Tag = 'fileEDFMat';
            app.TextArea.Editable = 'off';
            app.StartAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.ChangeAmplitudeofgenerictemplateforSpikedetectionLabel = uilabel(app.UIFigure);
            app.ChangeAmplitudeofgenerictemplateforSpikedetectionLabel.Text = 'Change Amplitude of generic template for Spike detection';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'µV';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 50;
            app.StartofanalysisLabel = uilabel(app.UIFigure);
            app.StartofanalysisLabel.Text = 'Start of analysis:';
            app.DurationofanalysisLabel = uilabel(app.UIFigure);
            app.DurationofanalysisLabel.Text = 'Duration of analysis:';
            app.SliceRecordingDropDownLabel = uilabel(app.UIFigure);
            app.SliceRecordingDropDownLabel.Text = 'Slice Recording';
            app.SliceRecordingDropDown = uidropdown(app.UIFigure);
            app.SliceRecordingDropDown.Items = {'No', 'Yes'};
            app.SliceRecordingDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceRecordingDropDownValueChanged, true);
            app.SliceRecordingDropDown.Value = 'No';
            app.SliceDurationLabel = uilabel(app.UIFigure);
            app.SliceDurationLabel.Text = 'Slice Duration: ';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 60;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 5;
            app.insecondsLabel = uilabel(app.UIFigure);
            app.insecondsLabel.Text = 'in seconds';
            app.insecondsLabel_2 = uilabel(app.UIFigure);
            app.insecondsLabel_2.Text = 'in seconds';
            app.inminutescantgobelow2minutesLabel = uilabel(app.UIFigure);
            app.inminutescantgobelow2minutesLabel.Text = 'in minutes (can''t go below 2 minutes)';
            app.LoadpreviousanalysisButton = uibutton(app.UIFigure, 'push');
            app.LoadpreviousanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpreviousanalysisButtonPushed, true);
            app.LoadpreviousanalysisButton.Text = 'Load previous analysis';
            app.MontagechoiceLabel = uilabel(app.UIFigure);
            app.MontagechoiceLabel.Text = 'Montage choice: ';
            app.MontageDropDown = uidropdown(app.UIFigure);
            app.MontageDropDown.Items = {'longitudinal montage ', 'transverse montage', 'referential montage'};
            app.MontageDropDown.ItemsData = [1 2 3];
            app.MontageDropDown.ValueChangedFcn = createCallbackFcn(app, @MontageDropDownValueChanged, true);
            app.MontageDropDown.Value = 1;
            app.TotalfiledurationEditFieldLabel = uilabel(app.UIFigure);
            app.TotalfiledurationEditFieldLabel.Visible = 'off';
            app.TotalfiledurationEditFieldLabel.Text = 'Total file duration:';
            app.TotalfiledurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalfiledurationEditField.Editable = 'off';
            app.TotalfiledurationEditField.Visible = 'off';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StartGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end