classdef Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SizeWindowsLabel       matlab.ui.control.Label
        SizeWindowDropDown     matlab.ui.control.DropDown
        GlobalstatisticsPanel  matlab.ui.container.Panel
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        SWIGTextArea           matlab.ui.control.TextArea
        SWIGTextAreaLabel      matlab.ui.control.Label
        SWFTextArea_2          matlab.ui.control.TextArea
        SWFTextArea_2Label     matlab.ui.control.Label
        SWITextArea_2          matlab.ui.control.TextArea
        SWITextArea_2Label     matlab.ui.control.Label
        LocalstatisticsPanel   matlab.ui.container.Panel
        secwspikesTextArea     matlab.ui.control.TextArea
        secwspikesLabel        matlab.ui.control.Label
        spikesTextArea         matlab.ui.control.TextArea
        spikesTextAreaLabel    matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        SWIGTextArea_2         matlab.ui.control.TextArea
        SWIGTextArea_2Label    matlab.ui.control.Label
        SWITextArea            matlab.ui.control.TextArea
        SWITextAreaLabel       matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

%This app display the detected spikes above the original recordings
%Each channel is shown separately in the figure for 20 second periods, this window size is adjustable
%
%disp(Display,SpecificSpikesAdj,Recordings(CurrentRecording),TimeLineSWI,DetectionParameters, MontageChoice, PatientSpecificStats(CurrentRecording).Stat);
%
%  INPUTS:	
%   SecDetAdj                                       struct of spikes with adjusted start and end
%   Recordings(CurrentRecording)                    struct with recording's information 
%   TimeLineSWI                                     struct with location informations of spikes
%   DetectionParameters                             struct with detection parameters defined in the main          
%   MontageChoice                                   montage selected in the main (1 longitudinal, 2 transversal, 3 referential)
%   PatientSpecificStats(CurrentRecording).Stat     struct with statistical information of the detected spikes: SWI, SWIG
%                               


    properties (Access = private)
        CurrentPrint        % Current window displayed
        NumSecPrint         % Number of seconds in on window
        EndPrint            % Last window of the analysis
        NumSpikes           % number of seconds with spikes on the window
        NumSpikesIn80El     % number of spikes in >80% of the electrodes
        NumSpikesRaw        % number of spikes
        LocalSWI            % Spike wave index of the window displayed
        LocalIG             % SWIG of the window
        Count               % count to know which window is displayed
        Recording           % struct with recording's information
        MaxCount            % count number of last window of the analysis
        NumSec              % number of seconds per window
        Fs                  % frequency sampling
        SWLine              % ?
        SWLineIn80El        % ?
        SpikeLine           % ?
        TimeLineSWI         % struct with location informations of spikes
        DetSpikes           % struct with informations of size, shape of detected spikes
        DetectionParameters % struct with detection parameters defined in the main 
        montageChoice       % montage selected in the main (1 longitudinal, 2 transversal, 3 referential)
        Stats               %struct with statistical information of the detected spikes: SWI, SWIG
        time
    end

    methods (Access = public)
        
    function [] = disp(app,DetSpikes,Recording,TimeLineSWI,DetectionParameters,montageChoice,Stat)
    
        app.DetSpikes = DetSpikes;
        app.Recording = Recording;
        app.TimeLineSWI = TimeLineSWI;
        app.DetectionParameters = DetectionParameters;
        app.Fs = DetectionParameters.Fs;
        app.montageChoice = montageChoice;
        app.Stats = Stat;
        app.SWITextArea_2.Value = int2str(Stat.GlobalSWI);
        app.SWIGTextArea.Value = int2str(Stat.GlobalSWIG);
        app.SWFTextArea_2.Value = int2str(Stat.SWF);    
        NumDerivation = length(Recording.PositiveElectrodes(:,1));
        Offset = 100;
        
        if ~isempty(DetSpikes)
            for k=1:length(Recording.StartAnalysis)
                
                % *********
                %  x-axis *
                % *********
                NmbSec = Recording.EndAnalysis(k) - Recording.StartAnalysis(k);
                NmbPts = NmbSec*app.Fs;
                StartTime = Recording.Start_time + seconds(Recording.StartAnalysis(k));
                EndTime = StartTime + seconds(NmbSec);
                app.time = linspace(StartTime,EndTime,NmbPts);
                app.datetickzoom(app.UIAxes,'x');
        
                % ********************************
                %  Recordings and spikes dispaly *
                % ********************************
                hold(app.UIAxes);
                OffsetCount = 0;
                rawData = zeros(1,NmbPts);
                
                for Derivation=1:NumDerivation            
                    rawData = GetData(Recording,k,Derivation,DetectionParameters);
                    Data =  Filter(rawData,DetectionParameters);
                    ElData = Data' + OffsetCount*ones(1,length(Data'));
        
                    % Grid on the y-axis
                    base = OffsetCount*ones(1,NmbPts);
                    top = OffsetCount*ones(1,NmbPts)+25; % 25 = 250microvolt
                    bottom = OffsetCount*ones(1,NmbPts)-25;
                    plot(app.UIAxes,app.time,base,'Color',[.8 .8 .8]);
                    plot(app.UIAxes,app.time,top,'Color',[.8 .8 .8]);
                    plot(app.UIAxes,app.time,bottom,'Color',[.8 .8 .8]);
        
                    % Original recordings display (with alternating colors)            
                    if montageChoice == 1
                        if Derivation < NumDerivation/2 +1
                            plot(app.UIAxes,app.time,ElData,"Color",[0 0 1]);
                        else 
                            plot(app.UIAxes,app.time,ElData,"Color",[1 0 0]);
                        end
                    elseif montageChoice == 2
                        if ismember(Derivation, [4,5,8,9,12,13,14]) 
                            plot(app.UIAxes,app.time,ElData,"Color",[1 0 0]);
                        else 
                            plot(app.UIAxes,app.time,ElData,"Color",[0 0 1]);
                        end
                    elseif montageChoice == 3
                        if Derivation < 12
                            plot(app.UIAxes,app.time,ElData,"Color",[0 0 1]);
                        else 
                            plot(app.UIAxes,app.time,ElData,"Color",[1 0 0]);
                        end
                    end
        
                    % Detected spikes display (in green)
                    GreenCurve = nan(1,NmbPts);
                    if not(isempty(DetSpikes(Derivation).Epoch))
                        if not(isempty(DetSpikes(Derivation).Epoch(k)))
                            if not(isempty(DetSpikes(Derivation).Epoch(k).Det))
                                if length(DetSpikes(Derivation).Epoch(k).Det(:,1))>2
                                    for i=1:length(DetSpikes(Derivation).Epoch(k).Det(:,1))
                                        Start = round((DetSpikes(Derivation).Epoch(k).Det(i,1)/1000-Recording.StartAnalysis(k))*app.Fs); 
                                        End = round((DetSpikes(Derivation).Epoch(k).Det(i,2)/1000-Recording.StartAnalysis(k))*app.Fs);
                                        if (End < length(rawData')) && (Start>0)
                                            GreenCurve(Start:End) = ElData(Start:End);
                                        end
                                    end
                                end
                            end
                        end
                    end
                    plot(app.UIAxes,app.time,GreenCurve,'g');
         
                    OffsetCount = OffsetCount + Offset;
                end
        
                % ******************************
                %  Summary curve (top) dispaly *
                % ******************************
                OffsetCount = OffsetCount + Offset;
                SumSpikeLineEl = 10*TimeLineSWI(k).SumSpikeLineEl;
                SumSpikeLineEl = SumSpikeLineEl + OffsetCount*ones(1,length(SumSpikeLineEl));
                plot(app.UIAxes,app.time,SumSpikeLineEl);
                
                ElData = OffsetCount*ones(1,length(rawData));
                app.SWLine = zeros(1,length(rawData));
                app.SWLineIn80El = zeros(1,length(rawData));
                app.SpikeLine = zeros(1,length(rawData));
        
                % Different colors for the top curve according to the SWI index
                if ~isempty(TimeLineSWI(k).list)
                    for Index = 1:length(TimeLineSWI(k).list)
                        Start = TimeLineSWI(k).list(Index)*app.Fs;
                        Stop = Start+app.Fs;
                        if Stop>length(ElData)
                            Stop=length(ElData);
                        end
                        if Start == 0
                            Start = 1;
                        end
                        if Stop>Start
                            plot(app.UIAxes,app.time(Start:Stop),ElData(Start:Stop),'g'); 
                            app.SWLine(Start:Stop) = ones(1,length(Start:Stop));
                        end
                    end
                end
                if ~isempty(TimeLineSWI(k).listSpikeIn80El)
                    for Index = 1:length(TimeLineSWI(k).listSpikeIn80El)
                        Start = TimeLineSWI(k).listSpikeIn80El(Index)*app.Fs;
                        Stop = Start+app.Fs;
                        if Stop>length(ElData)
                            Stop=length(ElData);
                        end
                        if Start == 0
                            Start = 1;
                        end
                        if Stop>Start
                            plot(app.UIAxes,app.time(Start:Stop),ElData(Start:Stop),'r');
                            app.SWLineIn80El(Start:Stop) = ones(1,length(Start:Stop));
                        end
                    end
                end
                for Index = 1:round(length(app.SWLine)/app.Fs)-1
                    Start = (Index-1)*app.Fs+1;
                    Stop = Start + round(app.Fs/10);
                    plot(app.UIAxes,app.time(Start:Stop),ElData(Start:Stop),'k');
                end
                if ~isempty(TimeLineSWI(k).locsSpikes)  
                    for Index = 1:length(TimeLineSWI(k).locsSpikes)
                        Pointer = app.time(TimeLineSWI(k).locsSpikes(Index)); 
                        line(app.UIAxes,[Pointer Pointer], [0 OffsetCount], 'linewidth',0.5,'Color',[0 0 0]+0.5);
                        app.SpikeLine(TimeLineSWI(k).locsSpikes(Index)) = 1;
                    end
                end 
        
                % *******************
                %  Axis information *
                % *******************
                xlim(app.UIAxes,[app.time(1) app.time(end)]);
                ylim(app.UIAxes,[-100 OffsetCount+100]);
        
                % Y-axis: derivation names
                electrodes{NumDerivation} = [];
                for Derivation=1:NumDerivation
                    PositiveElectrode = convertStringsToChars(Recording.PositiveElectrodes(Derivation,:));
                    NegativeElectrode = convertStringsToChars(Recording.NegativeElectrodes(Derivation,:));
                    PositiveElectrode = strrep(PositiveElectrode,'EEG ','');
                    NegativeElectrode = strrep(NegativeElectrode,'EEG ','');
        
                    DerivString = string(PositiveElectrode) + ' - ' + string(NegativeElectrode);
                    electrodes(Derivation) = cellstr(DerivString);
                end
                
                ylabel_size = 100*NumDerivation - 100;
                set(app.UIAxes,'YTick',0:100:ylabel_size,'YTickLabel',electrodes)
                
                app.CurrentPrint = 1;
                app.NumSec = 20;
                app.NumSecPrint = app.NumSec*app.Fs;
                app.Count = 1;
                
                oldNumSec = app.NumSec;
                oldCount = app.Count;
                app.CurrentPrint = 1;
                app.NumSec = app.NumSec;
                app.NumSecPrint = app.NumSec*app.Fs;
                app.Count = 1;
                
                while app.CurrentPrint+app.NumSecPrint-1<=length(app.time)
                    app.NumSpikes(app.Count) = round(sum(app.SWLine(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1))/app.Fs);
                    app.NumSpikesIn80El(app.Count) = round(sum(app.SWLineIn80El(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1))/app.Fs);
                    app.NumSpikesRaw(app.Count) = sum(app.SpikeLine(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1));
                    app.LocalSWI(app.Count) = round(100*app.NumSpikes(app.Count)/app.NumSec);
                    app.LocalIG(app.Count) = round(100*app.NumSpikesIn80El(app.Count)/app.NumSec);
                    app.Count = app.Count + 1;
                    app.CurrentPrint = app.CurrentPrint+app.NumSecPrint;
        
                end
                app.EndPrint = app.CurrentPrint+app.NumSecPrint-1;
                app.MaxCount = app.Count -1;
                
                if floor(oldCount/(app.NumSec)*oldNumSec) > 0
                    app.Count = floor(oldCount/(app.NumSec)*oldNumSec);
                else 
                    app.Count = 1;
                end
                app.CurrentPrint = 1 + app.NumSecPrint*(app.Count - 1);

                app.Count = 1;
                app.CurrentPrint = 1;
                xlim(app.UIAxes,[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
                app.SWITextArea.Value = int2str(app.LocalSWI(app.Count));
                app.SWIGTextArea_2.Value = int2str(app.LocalIG(app.Count));
                app.spikesTextArea.Value = int2str(app.NumSpikesRaw(app.Count));
                app.secwspikesTextArea.Value = int2str(app.NumSpikes(app.Count));
       
                title(app.UIAxes,[app.Recording.name ' - Epoch: ' int2str(k) '-' int2str(app.Count)]);
                axtoolbar(app.UIAxes,{'restoreview'});
                pan 'off'
        
                close all;
            end
        end
    end
    end
    
    methods (Access = private)

        function printTitle(app, NumSec)
                
            oldNumSec = app.NumSec;
            oldCount = app.Count;
            app.CurrentPrint = 1;
            app.NumSec = NumSec;
            app.NumSecPrint = app.NumSec*app.Fs;
            app.Count = 1;

            while app.CurrentPrint+app.NumSecPrint-1<=length(app.time)
                app.NumSpikes(app.Count) = round(sum(app.SWLine(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1))/app.Fs);
                app.NumSpikesIn80El(app.Count) = round(sum(app.SWLineIn80El(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1))/app.Fs);
                app.NumSpikesRaw(app.Count) = sum(app.SpikeLine(app.CurrentPrint:app.CurrentPrint+app.NumSecPrint-1));
                app.LocalSWI(app.Count) = round(100*app.NumSpikes(app.Count)/app.NumSec);
                app.LocalIG(app.Count) = round(100*app.NumSpikesIn80El(app.Count)/app.NumSec);
                app.Count = app.Count + 1;
                app.CurrentPrint = app.CurrentPrint+app.NumSecPrint;
    
            end
            app.EndPrint = app.CurrentPrint+app.NumSecPrint-1;
            app.MaxCount = app.Count -1;
            
            if floor(oldCount/(app.NumSec)*oldNumSec) > 0
                app.Count = floor(oldCount/(app.NumSec)*oldNumSec);
            else 
                app.Count = 1;
            end
            app.CurrentPrint = 1 + app.NumSecPrint*(app.Count - 1);
        end

        function datetickzoom(app, varargin)

            % DATETICKZOOM Date formatted tick labels, automatically updated when zoomed or panned. 
            %   Arguments are completely identical to does of DATETICK. The argument
            %   DATEFORM is reset once zoomed or panned.
            %
            %   See also datetick, datestr, datenum
            
            if nargin==2 && isstruct(varargin{2}) && isfield(varargin{2},'Axes') && isscalar(varargin{2}.Axes)
                datetickdata = getappdata(varargin{2}.Axes,'datetickdata');
                if isstruct(datetickdata) && isfield(datetickdata,'axh') && datetickdata.axh==varargin{2}.Axes
                    axh = datetickdata.axh;
                    ax = datetickdata.ax;
                    dateform = datetickdata.dateform;
                    keep_ticks = datetickdata.keep_ticks;
                    if keep_ticks
                        set(axh,[ax,'TickMode'],'auto')
                        if ~isempty(dateform)
                            datetick(axh,ax,dateform,'keeplimits','keepticks')
                        else
                            datetick(axh,ax,'keeplimits','keepticks')
                        end
                    else
                        if ~isempty(dateform)
                            datetick(axh,ax,dateform,'keeplimits')
                        else
                            datetick(axh,ax,'keeplimits')
                        end
                    end
                end
            else
                [axh,ax,dateform,keep_ticks] = app.parseinputs(varargin);
                datetickdata = [];
                datetickdata.axh = axh;
                datetickdata.ax = ax;
                datetickdata.dateform = dateform;
                datetickdata.keep_ticks = keep_ticks;
                
                setappdata(axh,'datetickdata',datetickdata);
                set(zoom(axh),'ActionPostCallback',@datetickzoom)
                set(pan(get(axh,'parent')),'ActionPostCallback',@datetickzoom)
                datetick(varargin{:})
            end
        end

        function [axh,ax,dateform,keep_ticks] = parseinputs(app, v)
            %Parse Inputs
            
            % Defaults;
            nin = length(v);
            dateform = [];
            keep_ticks = 0;
            
            % check to see if an axes was specified
            if nin > 0 & ishandle(v{1}) & isequal(get(v{1},'type'),'axes') %#ok ishandle return is not scalar
                % use the axes passed in
                axh = v{1};
                v(1)=[];
                nin=nin-1;
            else
                % use gca
                axh = gca;
            end
            % Look for 'keepticks'
            for i=nin:-1:max(1,nin-1),
               if strcmpi(v{i},'keepticks'),
                  keep_ticks = 1;
                  v(i) = [];
                  nin = nin-1;
               end
            end
            
            if nin==0, 
               ax = 'x';
            else
               ax = v{1};
            end
            if nin > 1
                 % The dateform (Date Format) value should be a scalar or string constant
                 % check this out
                 dateform = v{2}; 
                 if (isnumeric(dateform) && length(dateform) ~= 1) && ~ischar(dateform)
                     error('The Date Format value should be a scalar or string');
                 end 
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Move the displayed window to the left, change the stats to correspond to spikes displayed
            if app.Count > 1
                app.CurrentPrint = app.CurrentPrint - app.NumSecPrint;
                app.Count = app.Count - 1;
                xlim(app.UIAxes,[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
                title(app.UIAxes,[app.Recording.name ' - Epoch: '  '-' int2str(app.Count)]);
                app.SWITextArea.Value = int2str(app.LocalSWI(app.Count));
                app.SWIGTextArea_2.Value = int2str(app.LocalIG(app.Count));
                app.spikesTextArea.Value = int2str(app.NumSpikesRaw(app.Count));
                app.secwspikesTextArea.Value = int2str(app.NumSpikes(app.Count));
                set(app.UIAxes,'xlim',[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Move the displayed window to the right, change the stats to correspond to spikes displayed
            if app.Count < app.MaxCount
                app.CurrentPrint = app.CurrentPrint + app.NumSecPrint;
                app.Count = app.Count + 1;
                xlim(app.UIAxes,[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
                title(app.UIAxes,[app.Recording.name ' - Epoch: '  '-' int2str(app.Count)]);                
                app.SWITextArea.Value = int2str(app.LocalSWI(app.Count));
                app.SWIGTextArea_2.Value = int2str(app.LocalIG(app.Count));
                app.spikesTextArea.Value = int2str(app.NumSpikesRaw(app.Count));
                app.secwspikesTextArea.Value = int2str(app.NumSpikes(app.Count));
                set(app.UIAxes,'xlim',[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
            end
        end

        % Value changed function: SizeWindowDropDown
        function SizeWindowDropDownValueChanged(app, event)
            %change window size and adapt statistics corresponding to
            %spikes displayed
            value = str2double(app.SizeWindowDropDown.Value);
            printTitle(app, value)
            app.NumSec = value;
            app.NumSecPrint = app.NumSec*app.Fs;
            xlim(app.UIAxes,[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
            title(app.UIAxes,[app.Recording.name ' - Epoch: '  '-' int2str(app.Count)]);
            app.SWITextArea.Value = int2str(app.LocalSWI(app.Count));
            app.SWIGTextArea_2.Value = int2str(app.LocalIG(app.Count));
            app.spikesTextArea.Value = int2str(app.NumSpikesRaw(app.Count));
            app.secwspikesTextArea.Value = int2str(app.NumSpikes(app.Count));
            set(app.UIAxes,'xlim',[app.time(app.CurrentPrint) app.time(app.CurrentPrint+app.NumSecPrint-1)]);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %Open a new app to save information of the analysis, allow to run the code faster if the analysis need to be reused
            saveFile(SaveFile, app.DetSpikes, app.Recording, app.TimeLineSWI, app.DetectionParameters, app.montageChoice, app.Stats);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LocalstatisticsPanel = uipanel(app.UIFigure);
            app.LocalstatisticsPanel.Title = 'Local statistics';
            app.SWITextAreaLabel = uilabel(app.LocalstatisticsPanel);
            app.SWITextAreaLabel.Text = 'SWI';
            app.SWITextArea = uitextarea(app.LocalstatisticsPanel);
            app.SWIGTextArea_2Label = uilabel(app.LocalstatisticsPanel);
            app.SWIGTextArea_2Label.Text = 'SWIG';
            app.SWIGTextArea_2 = uitextarea(app.LocalstatisticsPanel);
            app.Label = uilabel(app.LocalstatisticsPanel);
            app.Label.Text = '%';
            app.Label_4 = uilabel(app.LocalstatisticsPanel);
            app.Label_4.Text = '%';
            app.spikesTextAreaLabel = uilabel(app.LocalstatisticsPanel);
            app.spikesTextAreaLabel.Text = '# spikes';
            app.spikesTextArea = uitextarea(app.LocalstatisticsPanel);
            app.secwspikesLabel = uilabel(app.LocalstatisticsPanel);
            app.secwspikesLabel.Text = {'# sec w/'; 'spikes'};
            app.secwspikesTextArea = uitextarea(app.LocalstatisticsPanel);
            app.GlobalstatisticsPanel = uipanel(app.UIFigure);
            app.GlobalstatisticsPanel.Title = 'Global statistics';
            app.SWITextArea_2Label = uilabel(app.GlobalstatisticsPanel);
            app.SWITextArea_2Label.Text = 'SWI';
            app.SWITextArea_2 = uitextarea(app.GlobalstatisticsPanel);
            app.SWFTextArea_2Label = uilabel(app.GlobalstatisticsPanel);
            app.SWFTextArea_2Label.Text = 'SWF';
            app.SWFTextArea_2 = uitextarea(app.GlobalstatisticsPanel);
            app.SWIGTextAreaLabel = uilabel(app.GlobalstatisticsPanel);
            app.SWIGTextAreaLabel.Text = 'SWIG';
            app.SWIGTextArea = uitextarea(app.GlobalstatisticsPanel);
            app.Label_2 = uilabel(app.GlobalstatisticsPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.GlobalstatisticsPanel);
            app.Label_3.Text = '%';
            app.SizeWindowDropDown = uidropdown(app.UIFigure);
            app.SizeWindowDropDown.Items = {'5 seconds', '10 seconds', '20 seconds', '30 seconds', '60 seconds'};
            app.SizeWindowDropDown.ItemsData = {'5', '10 ', '20', '30', '60'};
            app.SizeWindowDropDown.ValueChangedFcn = createCallbackFcn(app, @SizeWindowDropDownValueChanged, true);
            app.SizeWindowDropDown.Value = '20';
            app.SizeWindowsLabel = uilabel(app.UIFigure);
            app.SizeWindowsLabel.Text = 'Size Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end