classdef LoadFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LoadButton            matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        ChooseDataFileButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        SpecificSpikesAdj   % Description
        Recordings          % Description
        TimeLineSWI         % Description
        DetectionParameters % Description
        S                   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseDataFileButton
        function ChooseDataFileButtonPushed(app, event)
            [fileName, ~] = uigetfile(...
            {'*.mat','Accepted files (*.mat)';...
            '*.mat','MAT-files (*.mat)';...
            '*.*',  'All Files (*.*)'},...
            'Choose the file:',...
            'MultiSelect','on');
            app.TextArea.Value = fileName;
            app.S = load(fileName, '-mat');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            disp(Display,app.S.saveData.SpecificSpikesAdj,app.S.saveData.Recordings,app.S.saveData.TimeLineSWI,app.S.saveData.DetectionParameters, app.S.saveData.montageChoice, app.S.saveData.Stats);
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';

            % Delete old instance
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseDataFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataFileButtonPushed, true);
            app.ChooseDataFileButton.Text = 'Choose Data File';
            app.TextArea = uitextarea(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadFile

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end